classdef HWCC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Menu1              matlab.ui.container.Menu
        Menu1_1            matlab.ui.container.Menu
        Menu_2             matlab.ui.container.Menu
        GridLayout         matlab.ui.container.GridLayout
        TabGroup1          matlab.ui.container.TabGroup
        Tab_Data           matlab.ui.container.Tab
        GridLayout1        matlab.ui.container.GridLayout
        Panel1_1           matlab.ui.container.Panel
        GridLayout1_1      matlab.ui.container.GridLayout
        TabGroup1_1        matlab.ui.container.TabGroup
        Tab1_1             matlab.ui.container.Tab
        GridLayout1_1_1    matlab.ui.container.GridLayout
        UITableData1_1     matlab.ui.control.Table
        Tab_Data1_2        matlab.ui.container.Tab
        GridLayout1_1_2    matlab.ui.container.GridLayout
        UITableData1_2     matlab.ui.control.Table
        Panel1_2           matlab.ui.container.Panel
        GridLayout1_2      matlab.ui.container.GridLayout
        TabGroup1_2        matlab.ui.container.TabGroup
        Tab2_1             matlab.ui.container.Tab
        GridLayout1_2_1    matlab.ui.container.GridLayout
        UITableData2_1     matlab.ui.control.Table
        Tab2_2             matlab.ui.container.Tab
        GridLayout1_2_2    matlab.ui.container.GridLayout
        UITableData2_2     matlab.ui.control.Table
        Panel1_3           matlab.ui.container.Panel
        GridLayout1_3_1    matlab.ui.container.GridLayout
        UITableData3_1     matlab.ui.control.Table
        Panel1_4           matlab.ui.container.Panel
        GridLayout1_4_1    matlab.ui.container.GridLayout
        UITableData4_1     matlab.ui.control.Table
        Tab_P3             matlab.ui.container.Tab
        ContextMenu        matlab.ui.container.ContextMenu
        Menu_tableData1_2  matlab.ui.container.Menu
        Menu_4             matlab.ui.container.Menu
        ContextMenu2       matlab.ui.container.ContextMenu
        Menu_tableData2_2  matlab.ui.container.Menu
        Menu2              matlab.ui.container.Menu
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: Menu1_1
        function Menu1_1Selected(app, event)
            %% 数据的加载
            filename='..\课程设计数据\No2019101629.xls';
            cell_data=readcell(filename,'sheet','防洪计算');
            % 1、水文站洪水特征统计 A3-D23
            app.UITableData1_1.ColumnName = cell_data(2,1:4);
            app.UITableData1_1.Data = cell_data(3:23,1:4);
            %             app.UITableData1_2.ColumnName = app.UITableData1_1.ColumnName;
            %             app.UITableData1_2.Data = app.UITableData1_1.Data;
            % 2、水文站典型洪水流量 F3-H32
            app.UITableData2_1.ColumnName = cell_data(2,6:8);
            app.UITableData2_1.Data = cell_data(3:32,6:8);
            % 3、坝址有关数据 N1-N11
            app.UITableData3_1.ColumnName = {'名称';'值'};
            app.UITableData3_1.Data = cell_data(1:11,13:14);
            % 4、特大洪水数据 M15-N16
            app.UITableData4_1.ColumnName = cell_data(14,13:14);
            app.UITableData4_1.Data = cell_data(15:16,13:14);
        end

        % Menu selected function: Menu_tableData1_2
        function Menu_tableData1_2Selected(app, event)
            temp =  cell2mat(app.UITableData1_1.Data);
            p = polyfit(temp(:,2),temp(:,4),1);
            temp_index = find(temp(:,4)==-1);
            temp(temp_index,4) = round(polyval(p,temp(temp_index,2)));
            app.UITableData1_2.ColumnName = app.UITableData1_1.ColumnName;
            app.UITableData1_2.Data = temp;
        end

        % Context menu opening function: ContextMenu2
        function ContextMenu2Opening(app, event)
            
        end

        % Menu selected function: Menu_tableData2_2
        function Menu_tableData2_2Selected(app, event)
            app.UITableData2_1.Data(:,1)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu1 = uimenu(app.UIFigure);
            app.Menu1.Text = '特定化预设';
            app.Menu1_1 = uimenu(app.Menu1);
            app.Menu1_1.MenuSelectedFcn = createCallbackFcn(app, @Menu1_1Selected, true);
            app.Menu1_1.Text = '一键导入全部数据（课设）';
            app.Menu_2 = uimenu(app.UIFigure);
            app.Menu_2.Text = '帮助';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup1 = uitabgroup(app.GridLayout);
            app.Tab_Data = uitab(app.TabGroup1);
            app.Tab_Data.Title = '导入数据';
            app.GridLayout1 = uigridlayout(app.Tab_Data);
            app.Panel1_4 = uipanel(app.GridLayout1);
            app.Panel1_4.Title = '历史洪水资料';
            app.GridLayout1_4_1 = uigridlayout(app.Panel1_4);
            app.UITableData4_1 = uitable(app.GridLayout1_4_1);
            app.UITableData4_1.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableData4_1.RowName = {};
            app.Panel1_3 = uipanel(app.GridLayout1);
            app.Panel1_3.Title = '流域其他资料';
            app.GridLayout1_3_1 = uigridlayout(app.Panel1_3);
            app.UITableData3_1 = uitable(app.GridLayout1_3_1);
            app.UITableData3_1.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableData3_1.RowName = {};
            app.Panel1_2 = uipanel(app.GridLayout1);
            app.Panel1_2.Title = '水文站典型洪水流量';
            app.GridLayout1_2 = uigridlayout(app.Panel1_2);
            app.TabGroup1_2 = uitabgroup(app.GridLayout1_2);
            app.Tab2_1 = uitab(app.TabGroup1_2);
            app.Tab2_1.Title = '原始数据';
            app.GridLayout1_2_1 = uigridlayout(app.Tab2_1);
            app.UITableData2_1 = uitable(app.GridLayout1_2_1);
            app.UITableData2_1.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableData2_1.RowName = {};
            app.Tab2_2 = uitab(app.TabGroup1_2);
            app.Tab2_2.Title = '插值生成等时段数据';
            app.GridLayout1_2_2 = uigridlayout(app.Tab2_2);
            app.UITableData2_2 = uitable(app.GridLayout1_2_2);
            app.UITableData2_2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableData2_2.RowName = {};
            app.Panel1_1 = uipanel(app.GridLayout1);
            app.Panel1_1.Title = '水文站典型洪水流量';
            app.GridLayout1_1 = uigridlayout(app.Panel1_1);
            app.TabGroup1_1 = uitabgroup(app.GridLayout1_1);
            app.Tab1_1 = uitab(app.TabGroup1_1);
            app.Tab1_1.Title = '原始数据';
            app.GridLayout1_1_1 = uigridlayout(app.Tab1_1);
            app.UITableData1_1 = uitable(app.GridLayout1_1_1);
            app.UITableData1_1.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableData1_1.RowName = {};
            app.Tab_Data1_2 = uitab(app.TabGroup1_1);
            app.Tab_Data1_2.Title = '插补展延';
            app.GridLayout1_1_2 = uigridlayout(app.Tab_Data1_2);
            app.UITableData1_2 = uitable(app.GridLayout1_1_2);
            app.UITableData1_2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableData1_2.RowName = {};
            app.Tab_P3 = uitab(app.TabGroup1);
            app.Tab_P3.Title = '经验适线';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu_tableData1_2 = uimenu(app.ContextMenu);
            app.Menu_tableData1_2.MenuSelectedFcn = createCallbackFcn(app, @Menu_tableData1_2Selected, true);
            app.Menu_tableData1_2.Text = '相关分析法进行插补';
            app.Menu_4 = uimenu(app.ContextMenu);
            app.Menu_4.Text = '有待开发...';
            % Assign app.ContextMenu
            app.UITableData1_2.ContextMenu = app.ContextMenu;
            app.ContextMenu2 = uicontextmenu(app.UIFigure);
            app.ContextMenu2.ContextMenuOpeningFcn = createCallbackFcn(app, @ContextMenu2Opening, true);
            app.Menu_tableData2_2 = uimenu(app.ContextMenu2);
            app.Menu_tableData2_2.MenuSelectedFcn = createCallbackFcn(app, @Menu_tableData2_2Selected, true);
            app.Menu_tableData2_2.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu2);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu2
            app.UITableData2_2.ContextMenu = app.ContextMenu2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HWCC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end